classdef ihm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        CONTROLTab                   matlab.ui.container.Tab
        ButtonGroup                  matlab.ui.container.ButtonGroup
        HliceButton                  matlab.ui.control.RadioButton
        RectilneaButton              matlab.ui.control.RadioButton
        GENERARTRAYECTORIACheckBox   matlab.ui.control.CheckBox
        ENVIARButton                 matlab.ui.control.Button
        CinemticaButtonGroup         matlab.ui.container.ButtonGroup
        MatrizJacobianaseccionesButton_2  matlab.ui.control.RadioButton
        MatrizJacobianadiscosButton  matlab.ui.control.RadioButton
        DescensodelgradienteButton   matlab.ui.control.RadioButton
        PuntovlidoLamp               matlab.ui.control.Lamp
        PuntovlidoLampLabel          matlab.ui.control.Label
        PosicinrealPanel             matlab.ui.container.Panel
        ErrorPanel                   matlab.ui.container.Panel
        XEditFieldLabel_5            matlab.ui.control.Label
        ErrorField                   matlab.ui.control.NumericEditField
        ErrorGauge                   matlab.ui.control.LinearGauge
        XEditFieldLabel_4            matlab.ui.control.Label
        XEditFieldLabel_3            matlab.ui.control.Label
        XEditFieldLabel_2            matlab.ui.control.Label
        ZEditField                   matlab.ui.control.NumericEditField
        ZEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        XEditFieldLabel              matlab.ui.control.Label
        CoordenadasPanel             matlab.ui.container.Panel
        ZmmSpinner                   matlab.ui.control.Spinner
        ZmmSpinnerLabel              matlab.ui.control.Label
        YmmSpinner                   matlab.ui.control.Spinner
        YmmSpinnerLabel              matlab.ui.control.Label
        XmmSpinner                   matlab.ui.control.Spinner
        XmmSpinnerLabel              matlab.ui.control.Label
        RESETButton                  matlab.ui.control.Button
        SIMULARButton                matlab.ui.control.Button
        ARDUINOTab                   matlab.ui.container.Tab
        Parametros                   matlab.ui.container.Panel
        Puerto                       matlab.ui.control.DropDown
        PuertoLabel                  matlab.ui.control.Label
        ConexionLamp                 matlab.ui.control.Lamp
        ConexinLampLabel             matlab.ui.control.Label
        ConectarButton               matlab.ui.control.Button
        RefreshButton                matlab.ui.control.Button
        Baudrate                     matlab.ui.control.DropDown
        BaudrateDropDownLabel        matlab.ui.control.Label
        CALIBRACINTab                matlab.ui.container.Tab
        TABLAmotores                 matlab.ui.container.ButtonGroup
        MOTOR15Button                matlab.ui.control.RadioButton
        MOTOR16Button                matlab.ui.control.RadioButton
        MOTOR14Button                matlab.ui.control.RadioButton
        MOTOR13Button                matlab.ui.control.RadioButton
        MOTOR12Button                matlab.ui.control.RadioButton
        MOTOR11Button                matlab.ui.control.RadioButton
        MOTOR10Button                matlab.ui.control.RadioButton
        MOTOR9Button                 matlab.ui.control.RadioButton
        MOTOR8Button                 matlab.ui.control.RadioButton
        MOTOR7Button                 matlab.ui.control.RadioButton
        MOTOR6Button                 matlab.ui.control.RadioButton
        MOTOR5Button                 matlab.ui.control.RadioButton
        MOTOR4Button                 matlab.ui.control.RadioButton
        MOTOR3Button                 matlab.ui.control.RadioButton
        MOTOR2Button                 matlab.ui.control.RadioButton
        MOTOR1Button                 matlab.ui.control.RadioButton
        PULSOSSpinner                matlab.ui.control.Spinner
        PULSOSSpinnerLabel           matlab.ui.control.Label
        FIJARHOMEButton              matlab.ui.control.Button
        CALIBRACIONButton            matlab.ui.control.Button
        COLISIONESTab                matlab.ui.container.Tab
        NTOTALDEAUTOCOLISIONESEditField  matlab.ui.control.NumericEditField
        NTOTALDEAUTOCOLISIONESEditFieldLabel  matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global homeConfig robot currentPos Arduino
            
            %hold (app.UIAxes, 'on');
            %xlabel(app.UIAxes,'x');
            %ylabel(app.UIAxes,'y');
            %zlabel(app.UIAxes,'z');
            %campos(app.UIAxes, [-2500 1500 3500]);
            %camup(app.UIAxes, [0 1 0]);
            %%rotate3d(app.UIAxes, 'on');
            
            app.Puerto.Items=seriallist;
            app.Baudrate.Items={'300', '600', '1200', '2400', '4800', '9600', '14400', '19200', '28800', '38400', '57600', '115200'};
            set(app.ConexionLamp,'Color','white');
            delete(instrfindall);
            Arduino = serial(app.Puerto.Value,'BaudRate',str2double(app.Baudrate.Value));
            fclose(Arduino);
            
            
            %% Discos/sección
            
            NLinks = [20 12 12 8];
            
            %% Posición relativa entre discos: Cada disco se encuentra girado pi/2 respecto al anterior añadiendole un desfase de un agujero de hilo al cambiar de fase
            
            Assembly = {[0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0 pi/2.0],
                        [(pi/2.0-2*pi/16) -pi/2.0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0],
                        [(pi/2.0-2*pi/16) -pi/2.0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0],
                        [(pi/2.0-2*pi/16) -pi/2.0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0 pi/2.0 -pi/2.0]};
        
            %% Se crea el robot con los parámetros anteriores
            
            robot = HRRobot(NLinks, Assembly);
        
            homeConfig = homeConfiguration(robot);
            robot.setConfig(homeConfig);
            
            currentPos = tform2trvec(robot.fKine(robot.Config));
            app.XEditField.Value=currentPos(1);
            app.YEditField.Value=currentPos(2);
            app.ZEditField.Value=currentPos(3);
            
            app.ErrorGauge.Value=0;
            app.PuntovlidoLamp.Color = 'White';
            app.UITable.Enable = 'off';
            app.TABLAmotores.Visible = 'off';
            app.PULSOSSpinner.Visible = 'off';
            app.FIJARHOMEButton.Visible = 'off';
            app.PULSOSSpinnerLabel.Visible = 'off';
            
            figure;
            %fig=figure ('Visible',"off");
            %show(robot,robot.Config);
            robot.plot2('BodyColor', 'b', 'EndLinkColor', 'g');
            
%             savefig(fig,'robot.fig');
%             fig = openfig('robot.fig', 'invisible');
%             ax = findobj(fig,'Type','Axes');
            
%             cla(app.UIAxes);
%             lightangle(45, 30);
%             copyobj(ax.Children, app.UIAxes);
            
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            global Arduino;
            
            if app.ConectarButton.Text== "CONECTAR"
                delete(instrfindall);
                Arduino = serial(app.Puerto.Value,'BaudRate',str2double(app.Baudrate.Value));
                set(Arduino,'DataBits',8);
                set(Arduino,'StopBits',1);
                set(Arduino,'Parity','none');
                set(Arduino,'Timeout',0.5);
                try
                    fopen(Arduino);
                    for i=1:4
                        set(app.ConectarButton,'BackgroundColor',[0.94 0.94 0.94]);
                        pause(0.3);
                        set(app.ConectarButton,'BackgroundColor',[0.6 1 0.6]);
                        pause(0.3);
                    end
                    set(app.ConexionLamp,'Color','green');
                    set(app.ConectarButton,'Text','DESCONECTAR');
                    msgbox("Conexión Correcta");
                catch
                    set(app.ConectarButton,'BackgroundColor','red');
                    msgbox("Conexión Incorrecta");
                end
            else
                try
                    fclose(Arduino);
                    set(app.ConectarButton,'BackgroundColor',[0.94 0.94 0.94]);
                    set(app.ConexionLamp,'Color','red');
                    set(app.ConectarButton,'Text','CONECTAR');
                    msgbox("Desconexion Correcta");
                catch
                    msgbox("Desconexion Fallida");
                end
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.Puerto.Items=seriallist;
            
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.XmmSpinner.Value=0;
            app.YmmSpinner.Value=0;
            app.ZmmSpinner.Value=0;
            app.XEditField.Value=0;
            app.YEditField.Value=0;
            app.ZEditField.Value=0;
            app.ErrorGauge.Value=0;
            app.PuntovlidoLamp.Color = 'White';
            app.UITable.Data = [];
            app.UITable.Enable = 'off';
            app.NTOTALDEAUTOCOLISIONESEditField.Value = 0;
            app.TABLAmotores.Visible = 'off';
            app.PULSOSSpinner.Visible = 'off';
            app.PULSOSSpinnerLabel.Visible = 'off';
            app.FIJARHOMEButton.Visible = 'off';
            startupFcn(app);
            
        end

        % Callback function: ErrorField, NTOTALDEAUTOCOLISIONESEditField, 
        % SIMULARButton, UITable, XEditField, YEditField, ZEditField
        function SIMULARButtonPushed(app, event)
            global targetPos tform  robot error iter newConfig currentPos n col homeConfig
            
            tform = trvec2tform(targetPos);
            [newConfig, error, iter] = robot.iKineJacobi(tform);
            robot.setConfig(newConfig);
            
            app.ErrorGauge.Value=error;
            app.ErrorField.Value=error;
            
            [n, col]=robot.colisiones (newConfig);
            
            if n>0
                app.PuntovlidoLamp.Color = 'Red';
                msgbox('Punto no válido: Se han producido autocolisiones');
                app.UITable.Enable = 'on';
                app.UITable.Data = col;
                app.NTOTALDEAUTOCOLISIONESEditField.Value = n;
            elseif error > 10
                app.PuntovlidoLamp.Color = 'Red';
                msgbox('Punto no válido: error de posición mayor de 10mm');
                else
                    app.PuntovlidoLamp.Color = 'Green';
                    msgbox('El punto introducido es válido');
                    
            end
            
            currentPos = tform2trvec(robot.fKine(robot.Config));
                
            app.XEditField.Value=currentPos(1);
            app.YEditField.Value=currentPos(2);
            app.ZEditField.Value=currentPos(3);
            
            figure;
            %fig=figure ('Visible',"off");
            %show(robot,robot.Config);
            robot.setConfig(newConfig);
            %robot.plot();
            robot.plot2('BodyColor', 'b', 'EndLinkColor', 'g');
            
%             savefig(fig,'robot.fig');
%             fig = openfig('robot.fig', 'invisible');
%             ax = findobj(fig,'Type','Axes');
            
%             cla(app.UIAxes);
%             lightangle(45, 30);
%             copyobj(ax.Children, app.UIAxes);
            
            robot.setConfig(homeConfig);
            
        end

        % Value changed function: XmmSpinner
        function XmmSpinnerValueChanged(app, event)
            global targetPos
            targetPos(1) = app.XmmSpinner.Value;
            
        end

        % Value changed function: YmmSpinner
        function YmmSpinnerValueChanged(app, event)
            global targetPos
            targetPos(2)= app.YmmSpinner.Value;
        end

        % Value changed function: ZmmSpinner
        function ZmmSpinnerValueChanged(app, event)
            global targetPos
            targetPos(3) = app.ZmmSpinner.Value;
            
        end

        % Button pushed function: CALIBRACIONButton
        function CALIBRACIONButtonPushed(app, event)
        global calibrar
        
            if app.CALIBRACIONButton.Text== "COMENZAR CALIBRACION"
                app.TABLAmotores.Visible = 'on';
                app.PULSOSSpinner.Visible = 'on';
                app.FIJARHOMEButton.Visible = 'on';
                app.PULSOSSpinnerLabel.Visible = 'on';
                set(app.CALIBRACIONButton,'Text','TERMINAR CALIBRACION');
                calibrar = 1;
                
                delete(instrfindall);
                Arduino = serial("COM5",'BaudRate',250000);
                set(Arduino,'DataBits',8);
                set(Arduino,'StopBits',1);
                set(Arduino,'Parity','none');
                set(Arduino,'Timeout',0.5);
                fopen(Arduino);
                pause(2);
                
                calibrarArduino(0);
                
            else
                app.TABLAmotores.Visible = 'off';
                app.PULSOSSpinner.Visible = 'off';
                app.FIJARHOMEButton.Visible = 'off';
                app.PULSOSSpinnerLabel.Visible = 'off';
                set(app.CALIBRACIONButton,'Text','COMENZAR CALIBRACION');
                calibrar = 0;
                
                fclose(Arduino);
            end
            
        end

        % Selection changed function: TABLAmotores
        function TABLAmotoresSelectionChanged(app, event)
            global pulsos fijar calibrar
            
            selectedButton = app.TABLAmotores.SelectedObject;
            
            while calibrar == 1
                if app.MOTOR1Button.Value == true
                    calibrarArduino(1,1,pulsos);
                    if fijar == 1
                        calibrarArduino(2,1);
                        fijar = 0;
                    end
                    
                elseif app.MOTOR2Button.Value == true
                    calibrarArduino(1,2,pulsos);
                    if fijar == 1
                        calibrarArduino(2,2);
                        fijar = 0;
                    end
                    
                elseif app.MOTOR3Button.Value == true
                    calibrarArduino(1,3,pulsos);
                    if fijar == 1
                        calibrarArduino(2,3);
                        fijar = 0;
                    end
                    
                elseif app.MOTOR4Button.Value == true
                    calibrarArduino(1,4,pulsos);
                    if fijar == 1
                        calibrarArduino(2,4);
                        fijar = 0;
                    end
                    
                elseif app.MOTOR5Button.Value == true
                    calibrarArduino(1,5,pulsos);
                    if fijar == 1
                        calibrarArduino(2,5);
                        fijar = 0;
                    end
                    
                elseif app.MOTOR6Button.Value == true
                    calibrarArduino(1,6,pulsos);
                    if fijar == 1
                        calibrarArduino(2,6);
                        fijar = 0;
                    end
                    
                elseif app.MOTOR7Button.Value == true
                    calibrarArduino(1,7,pulsos);
                    if fijar == 1
                        calibrarArduino(2,7);
                        fijar = 0;
                    end
                    
                elseif app.MOTOR8Button.Value == true
                    calibrarArduino(1,8,pulsos);
                    if fijar == 1
                        calibrarArduino(2,8);
                        fijar = 0;
                    end
                    
                elseif app.MOTOR9Button.Value == true
                    calibrarArduino(1,9,pulsos);
                    if fijar == 1
                        calibrarArduino(2,9);
                        fijar = 0;
                    end
                    
                elseif app.MOTOR10Button.Value == true
                    calibrarArduino(1,10,pulsos);
                    if fijar == 1
                        calibrarArduino(2,10);
                        fijar = 0;
                    end
                    
                elseif app.MOTOR11Button.Value == true
                    calibrarArduino(1,11,pulsos);
                    if fijar == 1
                        calibrarArduino(2,11);
                        fijar = 0;
                    end
                    
                elseif app.MOTOR12Button.Value == true
                    calibrarArduino(1,12,pulsos);
                    if fijar == 1
                        calibrarArduino(2,12);
                        fijar = 0;
                    end
                    
                elseif app.MOTOR13Button.Value == true
                    calibrarArduino(1,13,pulsos);
                    if fijar == 1
                        calibrarArduino(2,13);
                        fijar = 0;
                    end
                    
                elseif app.MOTOR14Button.Value == true
                    calibrarArduino(1,14,pulsos);
                    if fijar == 1
                        calibrarArduino(2,14);
                        fijar = 0;
                    end
                    
                elseif app.MOTOR15Button.Value == true
                    calibrarArduino(1,15,pulsos);
                    if fijar == 1
                        calibrarArduino(2,15);
                        fijar = 0;
                    end
                    
                elseif app.MOTOR16Button.Value == true
                    calibrarArduino(1,16,pulsos);
                    if fijar == 1
                        calibrarArduino(2,16);
                        fijar = 0;
                    end
                    
                end
            end
                
        end

        % Value changed function: PULSOSSpinner
        function PULSOSSpinnerValueChanged(app, event)
            global pulsos
            
            pulsos = app.PULSOSSpinner.Value;
            
        end

        % Button pushed function: FIJARHOMEButton
        function FIJARHOMEButtonPushed(app, event)
            global fijar;
            
            fijar = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CONTROLTab = uitab(app.TabGroup);
            app.CONTROLTab.Title = 'CONTROL';
            app.SIMULARButton = uibutton(app.CONTROLTab, 'push');
            app.SIMULARButton.ButtonPushedFcn = createCallbackFcn(app, @SIMULARButtonPushed, true);
            app.SIMULARButton.Text = 'SIMULAR';
            app.RESETButton = uibutton(app.CONTROLTab, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.CoordenadasPanel = uipanel(app.CONTROLTab);
            app.CoordenadasPanel.Title = 'Coordenadas';
            app.XmmSpinnerLabel = uilabel(app.CoordenadasPanel);
            app.XmmSpinnerLabel.Text = 'X (mm)';
            app.XmmSpinner = uispinner(app.CoordenadasPanel);
            app.XmmSpinner.ValueChangedFcn = createCallbackFcn(app, @XmmSpinnerValueChanged, true);
            app.YmmSpinnerLabel = uilabel(app.CoordenadasPanel);
            app.YmmSpinnerLabel.Text = 'Y (mm)';
            app.YmmSpinner = uispinner(app.CoordenadasPanel);
            app.YmmSpinner.ValueChangedFcn = createCallbackFcn(app, @YmmSpinnerValueChanged, true);
            app.ZmmSpinnerLabel = uilabel(app.CoordenadasPanel);
            app.ZmmSpinnerLabel.Text = 'Z (mm)';
            app.ZmmSpinner = uispinner(app.CoordenadasPanel);
            app.ZmmSpinner.ValueChangedFcn = createCallbackFcn(app, @ZmmSpinnerValueChanged, true);
            app.PosicinrealPanel = uipanel(app.CONTROLTab);
            app.PosicinrealPanel.Title = 'Posición real';
            app.XEditFieldLabel = uilabel(app.PosicinrealPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.PosicinrealPanel, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @SIMULARButtonPushed, true);
            app.YEditFieldLabel = uilabel(app.PosicinrealPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.PosicinrealPanel, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @SIMULARButtonPushed, true);
            app.ZEditFieldLabel = uilabel(app.PosicinrealPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.PosicinrealPanel, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @SIMULARButtonPushed, true);
            app.XEditFieldLabel_2 = uilabel(app.PosicinrealPanel);
            app.XEditFieldLabel_2.Text = 'mm';
            app.XEditFieldLabel_3 = uilabel(app.PosicinrealPanel);
            app.XEditFieldLabel_3.Text = 'mm';
            app.XEditFieldLabel_4 = uilabel(app.PosicinrealPanel);
            app.XEditFieldLabel_4.Text = 'mm';
            app.ErrorPanel = uipanel(app.PosicinrealPanel);
            app.ErrorPanel.Title = 'Error';
            app.ErrorGauge = uigauge(app.ErrorPanel, 'linear');
            app.ErrorGauge.Limits = [0 5];
            app.ErrorField = uieditfield(app.ErrorPanel, 'numeric');
            app.ErrorField.ValueChangedFcn = createCallbackFcn(app, @SIMULARButtonPushed, true);
            app.XEditFieldLabel_5 = uilabel(app.ErrorPanel);
            app.XEditFieldLabel_5.Text = 'mm';
            app.PuntovlidoLampLabel = uilabel(app.CONTROLTab);
            app.PuntovlidoLampLabel.Text = 'Punto válido';
            app.PuntovlidoLamp = uilamp(app.CONTROLTab);
            app.CinemticaButtonGroup = uibuttongroup(app.CONTROLTab);
            app.CinemticaButtonGroup.Title = 'Cinemática';
            app.DescensodelgradienteButton = uiradiobutton(app.CinemticaButtonGroup);
            app.DescensodelgradienteButton.Text = 'Descenso del gradiente';
            app.DescensodelgradienteButton.Value = true;
            app.MatrizJacobianadiscosButton = uiradiobutton(app.CinemticaButtonGroup);
            app.MatrizJacobianadiscosButton.Text = 'Matriz Jacobiana (discos)';
            app.MatrizJacobianaseccionesButton_2 = uiradiobutton(app.CinemticaButtonGroup);
            app.MatrizJacobianaseccionesButton_2.Text = 'Matriz Jacobiana (secciones)';
            app.ENVIARButton = uibutton(app.CONTROLTab, 'push');
            app.ENVIARButton.Text = 'ENVIAR';
            app.GENERARTRAYECTORIACheckBox = uicheckbox(app.CONTROLTab);
            app.GENERARTRAYECTORIACheckBox.Text = 'GENERAR TRAYECTORIA';
            app.ButtonGroup = uibuttongroup(app.CONTROLTab);
            app.RectilneaButton = uiradiobutton(app.ButtonGroup);
            app.RectilneaButton.Text = 'Rectilínea';
            app.RectilneaButton.Value = true;
            app.HliceButton = uiradiobutton(app.ButtonGroup);
            app.HliceButton.Text = 'Hélice';
            app.ARDUINOTab = uitab(app.TabGroup);
            app.ARDUINOTab.Title = 'ARDUINO';
            app.Parametros = uipanel(app.ARDUINOTab);
            app.BaudrateDropDownLabel = uilabel(app.Parametros);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.Baudrate = uidropdown(app.Parametros);
            app.Baudrate.Items = {};
            app.Baudrate.Value = {};
            app.RefreshButton = uibutton(app.Parametros, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'REFRESH COM';
            app.ConectarButton = uibutton(app.Parametros, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'CONECTAR';
            app.ConexinLampLabel = uilabel(app.Parametros);
            app.ConexinLampLabel.Text = 'Conexión';
            app.ConexionLamp = uilamp(app.Parametros);
            app.PuertoLabel = uilabel(app.Parametros);
            app.PuertoLabel.Text = 'Puerto';
            app.Puerto = uidropdown(app.Parametros);
            app.Puerto.Items = {};
            app.Puerto.Value = {};
            app.CALIBRACINTab = uitab(app.TabGroup);
            app.CALIBRACINTab.Title = 'CALIBRACIÓN';
            app.CALIBRACIONButton = uibutton(app.CALIBRACINTab, 'push');
            app.CALIBRACIONButton.ButtonPushedFcn = createCallbackFcn(app, @CALIBRACIONButtonPushed, true);
            app.CALIBRACIONButton.Text = 'COMENZAR CALIBRACION';
            app.FIJARHOMEButton = uibutton(app.CALIBRACINTab, 'push');
            app.FIJARHOMEButton.ButtonPushedFcn = createCallbackFcn(app, @FIJARHOMEButtonPushed, true);
            app.FIJARHOMEButton.Text = 'FIJAR HOME';
            app.PULSOSSpinnerLabel = uilabel(app.CALIBRACINTab);
            app.PULSOSSpinnerLabel.Text = 'PULSOS +/-';
            app.PULSOSSpinner = uispinner(app.CALIBRACINTab);
            app.PULSOSSpinner.ValueChangedFcn = createCallbackFcn(app, @PULSOSSpinnerValueChanged, true);
            app.TABLAmotores = uibuttongroup(app.CALIBRACINTab);
            app.TABLAmotores.SelectionChangedFcn = createCallbackFcn(app, @TABLAmotoresSelectionChanged, true);
            app.MOTOR1Button = uiradiobutton(app.TABLAmotores);
            app.MOTOR1Button.Text = 'MOTOR 1';
            app.MOTOR1Button.Value = true;
            app.MOTOR2Button = uiradiobutton(app.TABLAmotores);
            app.MOTOR2Button.Text = 'MOTOR 2';
            app.MOTOR3Button = uiradiobutton(app.TABLAmotores);
            app.MOTOR3Button.Text = 'MOTOR 3';
            app.MOTOR4Button = uiradiobutton(app.TABLAmotores);
            app.MOTOR4Button.Text = 'MOTOR 4';
            app.MOTOR5Button = uiradiobutton(app.TABLAmotores);
            app.MOTOR5Button.Text = 'MOTOR 5';
            app.MOTOR6Button = uiradiobutton(app.TABLAmotores);
            app.MOTOR6Button.Text = 'MOTOR 6';
            app.MOTOR7Button = uiradiobutton(app.TABLAmotores);
            app.MOTOR7Button.Text = 'MOTOR 7';
            app.MOTOR8Button = uiradiobutton(app.TABLAmotores);
            app.MOTOR8Button.Text = 'MOTOR 8';
            app.MOTOR9Button = uiradiobutton(app.TABLAmotores);
            app.MOTOR9Button.Text = 'MOTOR 9';
            app.MOTOR10Button = uiradiobutton(app.TABLAmotores);
            app.MOTOR10Button.Text = 'MOTOR 10';
            app.MOTOR11Button = uiradiobutton(app.TABLAmotores);
            app.MOTOR11Button.Text = 'MOTOR 11';
            app.MOTOR12Button = uiradiobutton(app.TABLAmotores);
            app.MOTOR12Button.Text = 'MOTOR 12';
            app.MOTOR13Button = uiradiobutton(app.TABLAmotores);
            app.MOTOR13Button.Text = 'MOTOR 13';
            app.MOTOR14Button = uiradiobutton(app.TABLAmotores);
            app.MOTOR14Button.Text = 'MOTOR 14';
            app.MOTOR16Button = uiradiobutton(app.TABLAmotores);
            app.MOTOR16Button.Text = 'MOTOR 16';
            app.MOTOR15Button = uiradiobutton(app.TABLAmotores);
            app.MOTOR15Button.Text = 'MOTOR 15';
            app.COLISIONESTab = uitab(app.TabGroup);
            app.COLISIONESTab.Title = 'COLISIONES';
            app.UITable = uitable(app.COLISIONESTab);
            app.UITable.ColumnName = {'Disco 1'; 'Disco 2'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @SIMULARButtonPushed, true);
            app.NTOTALDEAUTOCOLISIONESEditFieldLabel = uilabel(app.COLISIONESTab);
            app.NTOTALDEAUTOCOLISIONESEditFieldLabel.Text = 'Nº TOTAL DE AUTOCOLISIONES';
            app.NTOTALDEAUTOCOLISIONESEditField = uieditfield(app.COLISIONESTab, 'numeric');
            app.NTOTALDEAUTOCOLISIONESEditField.ValueChangedFcn = createCallbackFcn(app, @SIMULARButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ihm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end